classdef app_phasefeedback < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        ActivefeedbackCheckBox  matlab.ui.control.CheckBox
        UIAxes                  matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: ActivefeedbackCheckBox
        function ActivefeedbackCheckBoxValueChanged(app, event)
            value = app.ActivefeedbackCheckBox.Value;
            PHASE_DP = "FLASH.RF/LLRF.CONTROLLER/CTRL.POLARIX/SP.PHASE";
            SPECTRUM_X = "FLASH.DIAG/CAMERA/OTR9FL2XTDS/SPECTRUM.X.MEAN";

            %%% finding the direction 
            phase=getfield(doocsread(PHASE_DP), 'data');
            x0 = getfield(doocsread(SPECTRUM_X), 'data');
            pahse_tep=doocswrite( PHASE_DP, phase+2 )
            x1 = getfield(doocsread(SPECTRUM_X), 'data');

            if x1>x0
                 delta_phase=0.5
            else
                 delta_phase=-0.5
            end 


            while app.ActivefeedbackCheckBox.Value
                for i=1:10
                    x(i)=getfield(doocsread(SPECTRUM_X), 'data');
                    y(i)=getfield(doocsread(PHASE_DP), 'data');
                    scatter(app.UIAxes, x(i),y(i), 'o', 'MarkerFaceColor',[.86 0.9 0.78], 'SizeData',300)

                    hold on
                    pause(0.1)
                end
                x(11)=mean(x);
                y(11)=mean(y);
                scatter(app.UIAxes, x(11),y(11), 'o', 'MarkerFaceColor',[0.85 0.9 0.78], 'SizeData',300)

                hold off


                if x(11)>10
                    while x2>11

                        phase=getfield(doocsread(PHASE_DP), 'data')
                        tem=doocswrite(PHASE_DP, phase-delta_phase);
                        pause(0.5)
                        x2=getfield(doocsread(SPECTRUM_X), 'data');
                    end
                end
                %  end
                % end

                if x(11)<4
                    while x2<4
                        phase=getfield(doocsread(PHASE_DP), 'data')
                        tem=doocswrite(PHASE_DP, phase+delta_phase);
                        pause(0.5)
                        x2=getfield(doocsread(SPECTRUM_X), 'data');
                        
                    end
                end


            end


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'TDS phase')
            zlabel(app.UIAxes, 'Z')
            app.ActivefeedbackCheckBox = uicheckbox(app.UIFigure);
            app.ActivefeedbackCheckBox.ValueChangedFcn = createCallbackFcn(app, @ActivefeedbackCheckBoxValueChanged, true);
            app.ActivefeedbackCheckBox.Text = 'Active feedback ';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_phasefeedback

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end